classdef select_filters_listbox < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    UIFigure                     matlab.ui.Figure
    SelectFiltersListBoxLabel    matlab.ui.control.Label
    SelectFiltersListBox         matlab.ui.control.ListBox
    SelectedFiltersListBoxLabel  matlab.ui.control.Label
    SelectedFiltersListBox       matlab.ui.control.ListBox
    PlotSimulationResults        matlab.ui.control.UIAxes
    SimulateButton               matlab.ui.control.Button
    SelectFiltersButton          matlab.ui.control.Button
    UnselectFiltersButton        matlab.ui.control.Button
  end

  % Callbacks that handle component events
  methods (Access = private)

    % Button pushed function: SelectFiltersButton
    function SelectFiltersButtonPushed(app, event)
      selected_filter_names = app.SelectFiltersListBox.Value;
      selected_filter_list = app.SelectedFiltersListBox.Items;
      
      if isempty(selected_filter_names)
        errordlg('No filters seleted. You need to select one or more filters.')
        return;
      end
      
      app.SelectedFiltersListBox.Items = sortrows([selected_filter_list,...
        selected_filter_names]','ascend');
      
      % Remove selected filter names from original list
      main_filter_list = app.SelectFiltersListBox.Items;
      updated_filter_list = setdiff(main_filter_list,selected_filter_names);
      
      app.SelectFiltersListBox.Items = sortrows(updated_filter_list,'ascend');
    end

    % Button pushed function: UnselectFiltersButton
    function UnselectFiltersButtonPushed(app, event)
      filters_to_unselect = app.SelectedFiltersListBox.Value;
      select_filters_list = app.SelectFiltersListBox.Items;
      
      if isempty(filters_to_unselect)
        errordlg('No filters chosen for unselection. You need to choose one or more filters.')
        return;
      end
      
      app.SelectFiltersListBox.Items = sortrows([select_filters_list,...
        filters_to_unselect]','ascend');
      
      % Remove filter name(s) from selected list
      main_selected_filters_list = app.SelectedFiltersListBox.Items;
      updated_selected_filters_list = setdiff(main_selected_filters_list,...
        filters_to_unselect);
      
      app.SelectedFiltersListBox.Items = sortrows(updated_selected_filters_list,'ascend');
    end

    % Button pushed function: SimulateButton
    function SimulateButtonPushed(app, event)
      % Get filters list
      filters_to_apply = app.SelectedFiltersListBox.Items;
      
      % Create some random data
      t = linspace(-2*pi,2*pi,1000);
      rng default  %initialize random number generator
      data = sin(t) + 0.25*rand(size(t));
      
      % plot the data
      plot(app.PlotSimulationResults,t,data);
      hold(app.PlotSimulationResults,'on');
      
      % If selected filters list is empty, throw a warning and plot raw
      % data. Otherwise, iterate through all selected filters and apply
      % filters to the raw data. Then plot the raw data and filtered data
      % in the same window
      if isempty(filters_to_apply)
        warndlg('No filters have been selected. Showing raw data');
        
      else
        % Apply filters
        for ii = 1:length(filters_to_apply)
          if strcmp(filters_to_apply{ii},'filter')
            % Create a filter window for filter
            filter_window = 5;
            b = (1/filter_window)*ones(1,filter_window);
            a = 1;
            
            % Apply filter to data
            filter_data = filter(b,a,data);
            
            % Plot filtered data
            plot(app.PlotSimulationResults,t,filter_data,'LineWidth',2,...
              'LineStyle','--');
            
          elseif strcmp(filters_to_apply{ii},'movmean')
            % Create a filter window for movmean
            movmean_window = 10;
            
            % Apply filter to data
            movmean_data = movmean(data,movmean_window);
            
            % Plot filtered data
            plot(app.PlotSimulationResults,t,movmean_data,'LineWidth',1.5,...
              'LineStyle',':');
          end
        end
        
      end
      
      % Set display properties
      axis(app.PlotSimulationResults,'tight')
      hold(app.PlotSimulationResults,'off')
      legend(app.PlotSimulationResults,[{'raw'},filters_to_apply]);
      
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.UIFigure = uifigure('Visible', 'off');
      app.UIFigure.Name = 'MATLAB App';
      app.SelectFiltersListBoxLabel = uilabel(app.UIFigure);
      app.SelectFiltersListBoxLabel.Text = 'Select Filters';
      app.SelectFiltersListBox = uilistbox(app.UIFigure);
      app.SelectFiltersListBox.Items = {'filter', 'movmean'};
      app.SelectFiltersListBox.Multiselect = 'on';
      app.SelectFiltersListBox.Tag = 'select_items';
      app.SelectFiltersListBox.Value = {'filter'};
      app.SelectedFiltersListBoxLabel = uilabel(app.UIFigure);
      app.SelectedFiltersListBoxLabel.Text = 'Selected Filters';
      app.SelectedFiltersListBox = uilistbox(app.UIFigure);
      app.SelectedFiltersListBox.Items = {};
      app.SelectedFiltersListBox.Multiselect = 'on';
      app.SelectedFiltersListBox.Tag = 'selected_filters';
      app.SelectedFiltersListBox.Value = {};
      app.PlotSimulationResults = uiaxes(app.UIFigure);
      title(app.PlotSimulationResults, 'Simulation Results')
      xlabel(app.PlotSimulationResults, 'time')
      ylabel(app.PlotSimulationResults, 'value')
      app.SimulateButton = uibutton(app.UIFigure, 'push');
      app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
      app.SimulateButton.Text = 'Simulate';
      app.SelectFiltersButton = uibutton(app.UIFigure, 'push');
      app.SelectFiltersButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFiltersButtonPushed, true);
      app.SelectFiltersButton.Text = '>>';
      app.UnselectFiltersButton = uibutton(app.UIFigure, 'push');
      app.UnselectFiltersButton.ButtonPushedFcn = createCallbackFcn(app, @UnselectFiltersButtonPushed, true);
      app.UnselectFiltersButton.Text = '<<';
      % Show the figure after all components are created
      app.UIFigure.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = select_filters_listbox

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.UIFigure)

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.UIFigure)
    end
  end
end