classdef select_filters_listbox < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    UIFigure                       matlab.ui.Figure
    VariablesInSelectedMATFileLabel  matlab.ui.control.Label
    VariablesInSelectedMATFileListBox  matlab.ui.control.ListBox
    SelectedFiltersListBoxLabel    matlab.ui.control.Label
    SelectedFiltersListBox         matlab.ui.control.ListBox
    PlotSimulationResults          matlab.ui.control.UIAxes
    SimulateButton                 matlab.ui.control.Button
    SelectFiltersButton            matlab.ui.control.Button
    UnselectFiltersButton          matlab.ui.control.Button
    ULEditFieldLabel               matlab.ui.control.Label
    ULEditField                    matlab.ui.control.NumericEditField
    LLEditFieldLabel               matlab.ui.control.Label
    LLEditField                    matlab.ui.control.NumericEditField
    FilterLimitApplyButton         matlab.ui.control.Button
    DirectoryLocationButton        matlab.ui.control.Button
    DirectoryLocationLabel         matlab.ui.control.Label
    AvailableMATFilesListBoxLabel  matlab.ui.control.Label
    AvailableMATFilesListBox       matlab.ui.control.ListBox
    ShowVariablesButton            matlab.ui.control.Button
  end

  
  properties (Access = private)
    Filter_ULs % Upper limit for all filters
    Filter_LLs % Lower limits for all filters
  end
  

  % Callbacks that handle component events
  methods (Access = private)

    % Button pushed function: SelectFiltersButton
    function SelectFiltersButtonPushed(app, event)
      selected_filter_names = app.VariablesInSelectedMATFileListBox.Value;
      selected_filter_list = app.SelectedFiltersListBox.Items;
      
      if isempty(selected_filter_names)
        errordlg('No filters seleted. You need to select one or more filters.')
        return;
      end
      
      app.SelectedFiltersListBox.Items = sortrows(unique([selected_filter_list,...
        selected_filter_names]'),'ascend');
    end

    % Button pushed function: UnselectFiltersButton
    function UnselectFiltersButtonPushed(app, event)
      filters_to_unselect = app.SelectedFiltersListBox.Value;
      
      if isempty(filters_to_unselect)
        errordlg('No filters chosen for unselection. You need to choose one or more filters.')
        return;
      end
      
      % Remove filter name(s) from selected list
      main_selected_filters_list = app.SelectedFiltersListBox.Items;
      updated_selected_filters_list = setdiff(main_selected_filters_list,...
        filters_to_unselect);
      
      app.SelectedFiltersListBox.Items = sortrows(updated_selected_filters_list,'ascend');
      
      % Remove UL/LL values from property
      for ii = 1:length(filters_to_unselect)
        if isfield(app.Filter_ULs,filters_to_unselect{ii})
          app.Filter_ULs = rmfield(app.Filter_ULs,filters_to_unselect{ii});
        end
        
        if isfield(app.Filter_LLs,filters_to_unselect{ii})
          app.Filter_LLs = rmfield(app.Filter_LLs,filters_to_unselect{ii});
        end
      end
      
      % Get new selected filter
      selected_filter = app.SelectedFiltersListBox.Value;
      
      if ~isempty(selected_filter) && isfield(app.Filter_ULs,selected_filter{1})
        app.ULEditField.Value = app.Filter_ULs.(selected_filter{1});
      else
        app.ULEditField.Value = Inf;
      end
      
      if ~isempty(selected_filter) && isfield(app.Filter_LLs,selected_filter{1})
        app.LLEditField.Value = app.Filter_LLs.(selected_filter{1});
      else
        app.LLEditField.Value = -Inf;
      end
    end

    % Button pushed function: SimulateButton
    function SimulateButtonPushed(app, event)
      % Get filters list
      filters_to_apply = app.SelectedFiltersListBox.Items;
      
      % Load MAT file
      mat_name = app.AvailableMATFilesListBox.Value;
      dir_name = app.DirectoryLocationLabel.Text;
      
      fname = fullfile(dir_name,mat_name);
      
      imported_data = load(fname);
      
      % Find data that fits within specified limits
      
      idx = ones(size(imported_data.data),'logical');
      t = 1:length(imported_data.data);
      
      for ii = 1:length(filters_to_apply)
        cur_filter = filters_to_apply{ii};
        
        cur_data = imported_data.(cur_filter);
        
        if isfield(app.Filter_ULs,cur_filter)
          idx = idx &...
            cur_data <= app.Filter_ULs.(cur_filter);
        end
        
        if isfield(app.Filter_LLs,cur_filter)
          idx = idx &...
            cur_data >= app.Filter_LLs.(cur_filter);
        end
      end
      
      if ~any(idx)
        errordlg('Filters have overconstrained the data.');
        return;
      end
      
      valid_t = t(idx);
      valid_data = imported_data.data(idx);
      
      % plot the data
      plot(app.PlotSimulationResults,valid_t,valid_data);
      % Set display properties
      axis(app.PlotSimulationResults,'tight')
      
    end

    % Button pushed function: DirectoryLocationButton
    function DirectoryLocationButtonPushed(app, event)
      dname = uigetdir('C:');
      
      if dname ~= 0
        app.DirectoryLocationLabel.Text = dname;
      end
      
      all_mat_files = dir(fullfile(dname,'*.mat'));
      app.AvailableMATFilesListBox.Items = {all_mat_files.name};
    end

    % Button pushed function: ShowVariablesButton
    function ShowVariablesButtonPushed(app, event)
      selected_file = app.AvailableMATFilesListBox.Value;
      dir_name = app.DirectoryLocationLabel.Text;
      
      full_file_name = fullfile(dir_name,selected_file);
      
      matObj = matfile(full_file_name);
      
      var_list = who(matObj);
      
      app.VariablesInSelectedMATFileListBox.Items = var_list;
    end

    % Button pushed function: FilterLimitApplyButton
    function FilterLimitApplyButtonPushed(app, event)
      selected_filter = app.SelectedFiltersListBox.Value;
      UL = app.ULEditField.Value;
      LL = app.LLEditField.Value;
      
      if isempty(selected_filter)
        errordlg('No filter was selected');
        return;
      end
      
      app.Filter_ULs.(selected_filter{1}) = UL;
      app.Filter_LLs.(selected_filter{1}) = LL;
    end

    % Value changed function: SelectedFiltersListBox
    function SelectedFiltersListBoxValueChanged(app, event)
      selected_filter = app.SelectedFiltersListBox.Value;
      
      if isfield(app.Filter_ULs,selected_filter)
        app.ULEditField.Value = app.Filter_ULs.(selected_filter{1});
      else
        app.ULEditField.Value = Inf;
      end
      
      if isfield(app.Filter_LLs,selected_filter)
        app.LLEditField.Value = app.Filter_LLs.(selected_filter{1});
      else
        app.LLEditField.Value = -Inf;
      end
      
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.UIFigure = uifigure('Visible', 'off');
      app.UIFigure.Name = 'MATLAB App';
      app.VariablesInSelectedMATFileLabel = uilabel(app.UIFigure);
      app.VariablesInSelectedMATFileLabel.Text = {'Variables In Selected '; 'MAT File'};
      app.VariablesInSelectedMATFileListBox = uilistbox(app.UIFigure);
      app.VariablesInSelectedMATFileListBox.Items = {};
      app.VariablesInSelectedMATFileListBox.Multiselect = 'on';
      app.VariablesInSelectedMATFileListBox.Tag = 'select_items';
      app.VariablesInSelectedMATFileListBox.Value = {};
      app.SelectedFiltersListBoxLabel = uilabel(app.UIFigure);
      app.SelectedFiltersListBoxLabel.Text = 'Selected Filters';
      app.SelectedFiltersListBox = uilistbox(app.UIFigure);
      app.SelectedFiltersListBox.Items = {};
      app.SelectedFiltersListBox.Multiselect = 'on';
      app.SelectedFiltersListBox.ValueChangedFcn = createCallbackFcn(app, @SelectedFiltersListBoxValueChanged, true);
      app.SelectedFiltersListBox.Tag = 'selected_filters';
      app.SelectedFiltersListBox.Value = {};
      app.PlotSimulationResults = uiaxes(app.UIFigure);
      title(app.PlotSimulationResults, 'Simulation Results')
      xlabel(app.PlotSimulationResults, 'time')
      ylabel(app.PlotSimulationResults, 'value')
      app.SimulateButton = uibutton(app.UIFigure, 'push');
      app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
      app.SimulateButton.Text = 'Simulate';
      app.SelectFiltersButton = uibutton(app.UIFigure, 'push');
      app.SelectFiltersButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFiltersButtonPushed, true);
      app.SelectFiltersButton.Text = '>>';
      app.UnselectFiltersButton = uibutton(app.UIFigure, 'push');
      app.UnselectFiltersButton.ButtonPushedFcn = createCallbackFcn(app, @UnselectFiltersButtonPushed, true);
      app.UnselectFiltersButton.Text = 'Remove Filter';
      app.ULEditFieldLabel = uilabel(app.UIFigure);
      app.ULEditFieldLabel.Text = 'UL';
      app.ULEditField = uieditfield(app.UIFigure, 'numeric');
      app.ULEditField.Value = Inf;
      app.LLEditFieldLabel = uilabel(app.UIFigure);
      app.LLEditFieldLabel.Text = 'LL';
      app.LLEditField = uieditfield(app.UIFigure, 'numeric');
      app.LLEditField.Value = -Inf;
      app.FilterLimitApplyButton = uibutton(app.UIFigure, 'push');
      app.FilterLimitApplyButton.ButtonPushedFcn = createCallbackFcn(app, @FilterLimitApplyButtonPushed, true);
      app.FilterLimitApplyButton.Text = 'Apply';
      app.DirectoryLocationButton = uibutton(app.UIFigure, 'push');
      app.DirectoryLocationButton.ButtonPushedFcn = createCallbackFcn(app, @DirectoryLocationButtonPushed, true);
      app.DirectoryLocationButton.Text = 'Directory Location';
      app.DirectoryLocationLabel = uilabel(app.UIFigure);
      app.DirectoryLocationLabel.Text = '';
      app.AvailableMATFilesListBoxLabel = uilabel(app.UIFigure);
      app.AvailableMATFilesListBoxLabel.Text = 'Available MAT Files';
      app.AvailableMATFilesListBox = uilistbox(app.UIFigure);
      app.AvailableMATFilesListBox.Items = {};
      app.AvailableMATFilesListBox.Value = {};
      app.ShowVariablesButton = uibutton(app.UIFigure, 'push');
      app.ShowVariablesButton.ButtonPushedFcn = createCallbackFcn(app, @ShowVariablesButtonPushed, true);
      app.ShowVariablesButton.Text = 'Show Variables';
      % Show the figure after all components are created
      app.UIFigure.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = select_filters_listbox

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.UIFigure)

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.UIFigure)
    end
  end
end