classdef select_filters_statebuttons < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    UIFigure               matlab.ui.Figure
    PlotSimulationResults  matlab.ui.control.UIAxes
    SimulateButton         matlab.ui.control.Button
    FilterButton           matlab.ui.control.StateButton
    MovmeanButton          matlab.ui.control.StateButton
    Label                  matlab.ui.control.Label
  end

  % Callbacks that handle component events
  methods (Access = private)

    % Button pushed function: SimulateButton
    function SimulateButtonPushed(app, event)
      % Get filters list
      is_filter_enabled = app.FilterButton.Value;
      is_movmean_enabled = app.MovmeanButton.Value;
      
      % Create some random data
      t = linspace(-2*pi,2*pi,1000);
      rng default  %initialize random number generator
      data = sin(t) + 0.25*rand(size(t));
      
      % Create legend text
      leg_txt = "raw";
      
      % plot the data
      plot(app.PlotSimulationResults,t,data);
      hold(app.PlotSimulationResults,'on');
      
      % If selected filters list is empty, throw a warning and plot raw
      % data. Otherwise, iterate through all selected filters and apply
      % filters to the raw data. Then plot the raw data and filtered data
      % in the same window
      if ~is_filter_enabled && ~is_movmean_enabled
        
        warndlg('No filters have been selected. Showing raw data');
        
      else
        % Apply filters
        
        if is_filter_enabled
          % Create a filter window for filter
          filter_window = 5;
          b = (1/filter_window)*ones(1,filter_window);
          a = 1;
          
          % Apply filter to data
          filter_data = filter(b,a,data);
          
          % Plot filtered data
          plot(app.PlotSimulationResults,t,filter_data,'LineWidth',2,...
            'LineStyle','--');
          
          % Update legend text
          leg_txt = [leg_txt , "filter"];
        end
        
        if is_movmean_enabled
          % Create a filter window for movmean
          movmean_window = 10;
          
          % Apply filter to data
          movmean_data = movmean(data,movmean_window);
          
          % Plot filtered data
          plot(app.PlotSimulationResults,t,movmean_data,'LineWidth',1.5,...
            'LineStyle',':');
          
          % Update legend text
          leg_txt = [leg_txt, "movmean"];
        end
        
      end
      
      % Set display properties
      axis(app.PlotSimulationResults,'tight')
      hold(app.PlotSimulationResults,'off')
      legend(app.PlotSimulationResults,leg_txt);
      
    end

    % Value changed function: FilterButton
    function FilterButtonValueChanged(app, event)
      value = app.FilterButton.Value;
      
      if value == 1
        app.FilterButton.BackgroundColor = [0 1 0];
      else
        app.FilterButton.BackgroundColor = [0.94 0.94 0.94];
      end
      
    end

    % Value changed function: MovmeanButton
    function MovmeanButtonValueChanged(app, event)
      value = app.MovmeanButton.Value;
      
      if value == 1
        app.MovmeanButton.BackgroundColor = [0 1 0];
      else
        app.MovmeanButton.BackgroundColor = [0.94 0.94 0.94];
      end
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.UIFigure = uifigure('Visible', 'off');
      app.UIFigure.Name = 'MATLAB App';
      app.PlotSimulationResults = uiaxes(app.UIFigure);
      title(app.PlotSimulationResults, 'Simulation Results')
      xlabel(app.PlotSimulationResults, 'time')
      ylabel(app.PlotSimulationResults, 'value')
      app.SimulateButton = uibutton(app.UIFigure, 'push');
      app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
      app.SimulateButton.Text = 'Simulate';
      app.FilterButton = uibutton(app.UIFigure, 'state');
      app.FilterButton.ValueChangedFcn = createCallbackFcn(app, @FilterButtonValueChanged, true);
      app.FilterButton.Text = 'Filter';
      app.MovmeanButton = uibutton(app.UIFigure, 'state');
      app.MovmeanButton.ValueChangedFcn = createCallbackFcn(app, @MovmeanButtonValueChanged, true);
      app.MovmeanButton.Text = 'Movmean';
      app.Label = uilabel(app.UIFigure);
      app.Label.Text = {'Click on the Filter and/or Movmean buttons to enable or disable the filters'; 'and then run a simulation'};
      % Show the figure after all components are created
      app.UIFigure.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = select_filters_statebuttons

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.UIFigure)

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.UIFigure)
    end
  end
end